classdef CoRSEER_I < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        OutputParametersLabel       matlab.ui.control.Label
        InputParametersLabel        matlab.ui.control.Label
        Image4                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        ModuleICalibrationLabel     matlab.ui.control.Label
        dotvarepsilonmathrmmmmathrmyr1EditField  matlab.ui.control.NumericEditField
        dotvarepsilonmathrmmmmathrmyr1Label  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ResetUIButton               matlab.ui.control.Button
        ChooseFolderEditField       matlab.ui.control.EditField
        ChooseFolderEditFieldLabel  matlab.ui.control.Label
        FileSelectButton            matlab.ui.control.Button
        SampleNameEditField         matlab.ui.control.EditField
        SampleNameEditFieldLabel    matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        CodeProgressGauge           matlab.ui.control.Gauge
        CodeProgressLabel           matlab.ui.control.Label
        ResolutionEditField         matlab.ui.control.NumericEditField
        mLabel                      matlab.ui.control.Label
        mathrmmumathrmmm1Slider     matlab.ui.control.RangeSlider
        mumathrmmm1Label            matlab.ui.control.Label
        log_10overlinesigmavarphi_mathrmomathrms1Slider  matlab.ui.control.RangeSlider
        log_10overlinesigmavarphi_mathrmomathrms1Label  matlab.ui.control.Label
        CalibrateButton             matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        FolderPath  % To store selected folder path % Description
        BreakExecution = false; % Description
        % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            close all;
            clc;

        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            folderPath = uigetdir;  % Opens a folder selection dialog
            figure(app.UIFigure);   % Brings the App window to front (optional)
            if folderPath ~= 0
                app.FolderPath = folderPath;  % Store folder path
                cd(folderPath);               % Change MATLAB current directory
                app.ChooseFolderEditField.Value = folderPath;  % If you're using an edit field
            end
        end

        % Button pushed function: ResetUIButton
        function ResetUIButtonPushed(app, event)
            app.BreakExecution = true;
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            % Clear any gauge or status fields if applicable
            app.CodeProgressGauge.Value = 0;
            app.UITable.Data = table();  % clear result table
            clear all;
            close all;
            clc;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            sheetName = strtrim(app.SampleNameEditField.Value);
            if isempty(sheetName)
                uialert(app.UIFigure, 'Please enter a valid sample name.', 'Missing Input');
                return;
            end
            excelFile = 'OSL_Calib.xlsx';
            try
                % Check if sheet exists
                [~, sheets] = xlsfinfo(excelFile);
                if ~ismember(sheetName, sheets)
                    uialert(app.UIFigure, sprintf('Sheet "%s" not found in OSL_Calib.xlsx', sheetName), 'Invalid Sheet');
                    return;
                end

                % Run your main calibration function
                runCoRSEERCalibration(app, sheetName);

                % Optional success message (non-intrusive)
                disp(['✅ Calibration complete for sheet: ' sheetName]);
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end

            function runCoRSEERCalibration(app, sheetName)
                %% 1 Loading Input Dataset
                data = readmatrix('OSL_Calib.xlsx', 'Sheet', sheetName); % input excel sheet
                depth = data(:, 1); % depth of experimental derived LDP in mm
                LxTx = data(:, 2); % Unnormalised Lx/Tx values from Risoe analyst (unitless)
                LxTxErr = data(:, 3); % Unnormalised Lx/Tx error values from Riose analyst (unitless)
                m = app.ResolutionEditField.Value; % density of the matrix
                logSP_range = app.log_10overlinesigmavarphi_mathrmomathrms1Slider.Value; % decay constant range on log-scale
                mu_range    = app.mathrmmumathrmmm1Slider.Value;        % attenuation coefficient in mm^-1
                e  = app.dotvarepsilonmathrmmmmathrmyr1EditField.Value; % erosion rates in mm/yr
                %% 2a Sigmoidal Curve Fitting (SCF) : Model, Input and Output
                ft = fittype('a / (1 + exp(-b*(x - c)))', 'independent', 'x', 'coefficients', {'a', 'b', 'c'}); % sigmoidal fitting equation
                opts = fitoptions(ft);
                opts.StartPoint = [max(LxTx), 1, median(depth)]; % Initial condition for fitting
                opts.Lower = [0, 0, 0]; % lowest search limit
                opts.Upper = [Inf, Inf, max(depth)]; % highest search limit
                opts.Weights = abs(LxTx ./ LxTxErr); % weight factor 
                [fitresult, gof] = fit(depth, LxTx, ft, opts); %final fitting
                ci = confint(fitresult, 0.95); % 95% confidence interval
                a = fitresult.a; b = fitresult.b; c = fitresult.c; % final results 
                dmax = ceil(max(depth)/4)*4; % depth values which will be used for plotting best fitting sigmoidal fit
                x_dense = linspace(0, dmax, 2000);
                f_dense = a ./ (1 + exp(-b * (x_dense - c))); % final sigmoidal curve y axis values used for plotting sigmoidal fit

                %% 2b SCF: Plot_1
                cla(app.UIAxes); hold(app.UIAxes, 'on'); % initial declaration for plotting
                errorbar(app.UIAxes, depth, LxTx, LxTxErr, 'ko'); % Experimental LDP plotting with errorbars
                plot(app.UIAxes, x_dense, f_dense, '-', 'LineWidth', 2, 'Color', '#008080'); % plotting the best fitting sigmoidal profile
                xlabel(app.UIAxes, 'Depth (mm)'); 
                ylabel(app.UIAxes, 'Lx/Tx');
                title(app.UIAxes, ['Sigmoidal Fitting ', sheetName], 'Interpreter', 'none');
                legend(app.UIAxes,'Lx/TxExp','Sigmoidal fit','Location', 'southeast');
                abctxt = sprintf('a = %.4g\nb = %.4g\nc = %.4g', a, b, c);
                text(app.UIAxes, 0.90, 0.3, abctxt, 'Units','normalized','HorizontalAlignment','right', 'VerticalAlignment','bottom','FontSize',15);
                box(app.UIAxes, 'on');
                axis(app.UIAxes, 'square');
                ylim(app.UIAxes, [0, 1.25 * max(LxTx)]);
                xlim(app.UIAxes, [0, dmax]);
                f1 = figure('Visible','on');
                ax1 = copyobj(app.UIAxes, f1);
                set(f1, 'Position', get(app.UIAxes, 'Position'));
                savefig(f1, sprintf('Plot1_%s.fig', sheetName));
                close(f1);

                %% 2c Estimating Standard Deviation and Normalising Lx/Tx
                std_1 = std(abs(LxTx - feval(fitresult, depth))/a); % estimating residual values between best fitting sigmoidal curve and experimental LDP
                NLS_exp = LxTx / a; % Normalising Lx/Tx with plateau value
                NLS_err = LxTxErr / a; % Normalising Lx/Tx Error with plateau value
                
                %%%%% Completion of Normalising process %%%%
                %% 3a Forward Modelling - Initial Declaration Variables
                dt = 0.01; % timestamp in years
                t_cal = data(1,7); % Exposure age of calibration sample in years
                r  = data(1,4);  % Order of Kinetics
                n_1 = round(t_cal / dt); % Defining number of iterations 
                d_exp = depth; % depth of the experimental LDP in mm
                D_r = data(1,5); % Dose Rates in Gy/yr
                Do  = data(1,6); % Characteristic Dose in Gy
                Df  = D_r/Do; % Dose Factor
                nd = 75; % Defining the division of depth for Depth profile smoothening
                d_syn = linspace(0, dmax, nd)'; % Synthetic LDP depth matrix
                dx = dmax/(nd-1); % depth interval taken for advection term simulation
                [log_sp_grid, mu_grid] = meshgrid(linspace(logSP_range(1), logSP_range(2), m), linspace(mu_range(1), mu_range(2), m));
                % declation of log SP and mu grid. 
                sp_vec = 10.^log_sp_grid(:); mu_vec = mu_grid(:); N = numel(sp_vec); % Converting into Nx1 matrix
                MF_vec = zeros(N,1); % Initial declaration of Misfit Vector.
                app.CodeProgressGauge.Value = 0;  % Resetting the code progress gauge
                drawnow;

                %% 3b Forward Modelling - Estimation of Misfit
                for idx = 1:N
                    sp = sp_vec(idx); 
                    mu = mu_vec(idx);
                    % creating pair of sp and mu with each iteration
                    NLS_syn = ones(nd,1); % declaring the intial condition of the LPD when the rock was buried
                    % if the erosion is non-zero
                    if e ~= 0
                        for t = 1:n_1
                            p = [NLS_syn; 1];  
                            adv = e * diff(p) / dx;
                            dNLS = (Df.*(1-NLS_syn)-NLS_syn.^r.*sp.* exp(-mu * d_syn)*365*24*3600 + adv)*dt;
                            NLS_syn = NLS_syn + dNLS;
                            NLS_syn(NLS_syn < 0 | NLS_syn > 1 | isnan(NLS_syn)) = 0; %eliminating any negative, infinite and >1 value
                        end
                    % if the erosion is nonzero
                    else
                        for t = 1:n_1
                            dNLS = (Df.*(1-NLS_syn)-NLS_syn.^r.*sp.*exp(-mu*d_syn)*365*24*3600)*dt;
                            NLS_syn = NLS_syn + dNLS;
                            NLS_syn(NLS_syn < 0 | NLS_syn > 1 | isnan(NLS_syn)) = 0; %eliminating any negative, infinite and >1 value
                        end
                    end
                    try
                        NLS_iplt = interp1(d_syn, NLS_syn, d_exp); % interpolating Synthetic LDP to the depth matrix of experimental LDP
                        MF_vec(idx) = sum((NLS_iplt - NLS_exp).^2) / std_1^2; % Chi-square estimation
                    catch
                        MF_vec(idx) = Inf;
                    end
                    % Update Gauge Progress
                    percent = round(100 * idx / N);
                    app.CodeProgressGauge.Value = percent;
                    drawnow limitrate;  % Update GUI without slowing loop too much
                end
                %% 4a Inverse Modelling: Establishing the normalised likelihood matrix,Best fitting pairs of sigmaphi and mu and Monte-Carlo filtered values
                likelihood = exp(-MF_vec / 2); % likelihood value estimated using Chi-square misfit function
                norm_likelihood = likelihood / max(likelihood); % normalised likelihood 0 to 1
                norm_likelihood_mat = reshape(norm_likelihood, m, m); % surface matrix of normalised likelihood 0 to 1
                sp_mat = reshape(sp_vec, m, m); 
                mu_mat = reshape(mu_vec, m, m);
                ind_MC_filt = find(norm_likelihood > rand(N,1));  % Index of pairs selected by monte carlo simulation
                MC_SP = log10(sp_vec(ind_MC_filt)); % Mont-Carlo filtered decay constant values on log-scale
                MC_mu = mu_vec(ind_MC_filt); % Monte-Carlo filtered mu values
                SP_BF = log10(sp_vec(norm_likelihood == 1)); % Best fitting Value of decay constant on log-scale
                mu_BF = mu_vec(norm_likelihood == 1); % Best fitting value of mu

                %% 4b Inverse Modelling: Creating Confidence intervals from Monte-Carlo filtered Values
                conf_prct = [2.5, 17.5, 50, 82.5, 97.5];
                SP_quant = prctile(MC_SP, conf_prct); % Establshing values based on percentile values
                mu_quant = prctile(MC_mu, conf_prct); % Establshing values based on percentile values
                SP_2sd = 10^SP_quant(1); % -2 sigma value of decay constant
                SP_1sd = 10^SP_quant(2); % -1 sigma value of decay constant
                SP_M   = 10^SP_quant(3); %  median value of decay constant
                SP_1su = 10^SP_quant(4); % +1 sigma value of decay constant
                SP_2su = 10^SP_quant(5); % +2 sigma value of decay constant
                SP_BF  = 10^mode(round(SP_BF*100)/100);% Best fitting value of decay constant
                mu_2sd = mu_quant(1); % -2 sigma value of attenuation Coefficient
                mu_1sd = mu_quant(2); % -1 sigma value of attenuation Coefficient
                mu_M   = mu_quant(3); %  Median value of attenuation Coefficient
                mu_1su = mu_quant(4); % +1 sigma value of attenuation Coefficient
                mu_2su = mu_quant(5); % +2 sigma value of attenuation Coefficient
                mu_BF  = mode(round(mu_BF*100)/100); % Best Fitting  sigma value of attenuation Coefficient

                %% 5a Final Results: Plot_2
                NLS_M = ones(nd,1); % Initial Codition of zero exposure
                for j = 1:n_1
                    p = [NLS_M;1];
                    adv =    (e)*diff(p)/dx;
                    NLS_M = NLS_M + (Df.*(1-NLS_M)-NLS_M.^r .* SP_M * 365*24*3600 .*exp(-mu_M * d_syn)+adv)*dt;
                end
                NLS_M = real(NLS_M); 
                NLS_M(NLS_M<0 | NLS_M>1 | isnan(NLS_M)) = 0; %eliminating any negative, infinite and >1 value
                % Model - Best Fit
                NLS_BF = ones(nd,1);
                for j = 1:n_1
                    p = [NLS_BF;1];
                    adv =    (e)*diff(p)/dx;
                    NLS_BF = NLS_BF + (Df.*(1-NLS_BF)-NLS_BF.^r .* SP_BF * 365*24*3600 .* exp(-mu_BF * d_syn)+adv) * dt;
                end
                NLS_BF = real(NLS_BF);
                NLS_BF(NLS_BF<0 | NLS_BF>1 | isnan(NLS_BF)) = 0;%eliminating any negative, infinite and >1 value
                % Experimental
                cla(app.UIAxes3); hold(app.UIAxes3, 'on');
                errorbar(app.UIAxes3, depth, NLS_exp, NLS_err, 'ko', 'DisplayName','NormLx/TxExp');
                plot(app.UIAxes3, d_syn, NLS_M, '-', 'LineWidth',2, 'Color', [0.8500 0.3250 0.0980], 'DisplayName','Median Fit');
                plot(app.UIAxes3, d_syn, NLS_BF, '--', 'LineWidth',2, 'Color', [0 0.4470 0.7410], 'DisplayName','Best Fit');
                title(app.UIAxes3, sprintf('Calibration LDP — %s', strrep(sheetName,'_',' ')), 'Interpreter','none');
                xlabel(app.UIAxes3, 'Depth (mm)');
                ylabel(app.UIAxes3, 'Normalised Lx/Tx');
                legend(app.UIAxes3, 'Location','southeast');
                ylim(app.UIAxes3, [-1.25*abs(min(NLS_exp)), 1.25*max(NLS_exp)]);
                xlim(app.UIAxes3, [0, dmax]);
                axis(app.UIAxes3, 'square');
                box(app.UIAxes3, 'on');
                hold(app.UIAxes3, 'off');
                f2 = figure('Visible','on');
                ax2 = copyobj(app.UIAxes3, f2);
                set(f2, 'Position', get(app.UIAxes3, 'Position'));
                savefig(f2, sprintf('Plot2_%s.fig', sheetName));
                close(f2);

                %% 5b Final Results: Plot_3
                cla(app.UIAxes4);
                surf(app.UIAxes4, sp_mat, mu_mat, norm_likelihood_mat, 'EdgeColor', 'none');
                view(app.UIAxes4, 2);
                colorbar(app.UIAxes4);
                xlabel(app.UIAxes4, 'Bleaching rate [s^{-1}]');
                ylabel(app.UIAxes4, 'Attenuation coeff. [mm^{-1}]');
                title(app.UIAxes4, sprintf('Probability— %s', strrep(sheetName,'_',' ')), 'Interpreter','none');
                xlim(app.UIAxes4, [min(sp_mat(:)), max(sp_mat(:))]);
                ylim(app.UIAxes4, [min(mu_mat(:)), max(mu_mat(:))]);
                clim(app.UIAxes4, [0, max(norm_likelihood_mat(:))]);
                axis(app.UIAxes4, 'square');
                box(app.UIAxes4, 'on');
                xline(app.UIAxes4, SP_M, '-', 'Color','w','LineWidth',1.5);
                yline(app.UIAxes4, mu_M, '-', 'Color','w','LineWidth',1.5);
                xline(app.UIAxes4, SP_BF, '--', 'Color','w','LineWidth',1.2);
                yline(app.UIAxes4, mu_BF, '--', 'Color','w','LineWidth',1.2);
                set(app.UIAxes4, 'XScale', 'log');
                f3 = figure('Visible','on');
                ax3 = copyobj(app.UIAxes4, f3);
                set(f3, 'Position', get(app.UIAxes4, 'Position'));
                savefig(f3, sprintf('Plot3_%s.fig', sheetName));
                close(f3);
                %% 5c Final Results : Displaying result table and output variables
                % SP and mu confidence bounds and best fits
                resultsTable = table;
                resultsTable.SP_BF = log10(SP_BF);
                resultsTable.SP_M = log10(SP_M);
                resultsTable.SP_1sd = log10(SP_1sd);
                resultsTable.SP_1su = log10(SP_1su);
                resultsTable.SP_2sd = log10(SP_2sd);
                resultsTable.SP_2su = log10(SP_2su);
                resultsTable.mu_BF = mu_BF;
                resultsTable.mu_M = mu_M;
                resultsTable.mu_1sd = mu_1sd;
                resultsTable.mu_1su = mu_1su;
                resultsTable.mu_2sd = mu_2sd;
                resultsTable.mu_2su = mu_2su;
                resultsTable.a = a;
                resultsTable.a_1sd = ci(1,1); % -1 sigma value a
                resultsTable.a_1su = ci(2,1); % +1 sigma value a
                resultsTable.b = b;
                resultsTable.b_1sd = ci(1,2); % -1 sigma value b
                resultsTable.b_1su = ci(2,2); % +1 sigma value b
                resultsTable.c = c;
                resultsTable.c_1sd = ci(1,3); % -1 sigma value b
                resultsTable.c_1su = ci(2,3); % +1 sigma value b
                resultsTable.R_square = gof.rsquare; % R-square value of fitting
                writetable(resultsTable, ['FitPar_' sheetName, '.xlsx']);
                % Convert table to cell array format
                varNames = resultsTable.Properties.VariableNames';
                varValues = table2cell(resultsTable)';
                % % Combine as 2-column cell array
                summaryCell = [varNames, varValues];
                % % Set to UITable
                app.UITable.Data = summaryCell;
                app.UITable.ColumnName = {'Parameter', 'Value'};
                save(sprintf('Variable_%s.mat', sheetName));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.CalibrateButton = uibutton(app.GridLayout, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.log_10overlinesigmavarphi_mathrmomathrms1Label = uilabel(app.GridLayout);
            app.log_10overlinesigmavarphi_mathrmomathrms1Label.Interpreter = 'latex';
            app.log_10overlinesigmavarphi_mathrmomathrms1Label.Text = '$\log_{10}(\overline{\sigma\varphi}_{\mathrm{o}})\,(\mathrm{s}^{-1})$';
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider = uislider(app.GridLayout, 'range');
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider.Limits = [-10 0];
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider.MajorTicks = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0];
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider.MajorTickLabels = {'-10', '-9', '-8', '-7', '-6', '-5', '-4', '-3', '-2', '-1', '0'};
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider.MinorTicks = [-10 -9.8 -9.6 -9.4 -9.2 -9 -8.8 -8.6 -8.4 -8.2 -8 -7.8 -7.6 -7.4 -7.2 -7 -6.8 -6.6 -6.4 -6.2 -6 -5.8 -5.6 -5.4 -5.2 -5 -4.8 -4.6 -4.4 -4.2 -4 -3.8 -3.6 -3.4 -3.2 -3 -2.8 -2.6 -2.4 -2.2 -2 -1.8 -1.6 -1.4 -1.2 -1 -0.800000000000001 -0.6 -0.4 -0.199999999999999 0];
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider.Step = 0.01;
            app.log_10overlinesigmavarphi_mathrmomathrms1Slider.Value = [-10 0];
            app.mumathrmmm1Label = uilabel(app.GridLayout);
            app.mumathrmmm1Label.Interpreter = 'latex';
            app.mumathrmmm1Label.Text = '$\mathrm\mu\,(\mathrm{mm}^{-1})$';
            app.mathrmmumathrmmm1Slider = uislider(app.GridLayout, 'range');
            app.mathrmmumathrmmm1Slider.Limits = [0 4];
            app.mathrmmumathrmmm1Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.mathrmmumathrmmm1Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.mathrmmumathrmmm1Slider.Value = [0 4];
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.GridLayout, 'numeric');
            app.ResolutionEditField.Value = 1000;
            app.CodeProgressLabel = uilabel(app.GridLayout);
            app.CodeProgressLabel.Text = 'Code Progress (%)';
            app.CodeProgressGauge = uigauge(app.GridLayout, 'circular');
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout);
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.SampleNameEditField = uieditfield(app.GridLayout, 'text');
            app.FileSelectButton = uibutton(app.GridLayout, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Icon = fullfile(pathToMLAPP, 'Icon_folder.png');
            app.FileSelectButton.Text = '';
            app.ChooseFolderEditFieldLabel = uilabel(app.GridLayout);
            app.ChooseFolderEditFieldLabel.Text = 'Choose Folder';
            app.ChooseFolderEditField = uieditfield(app.GridLayout, 'text');
            app.ResetUIButton = uibutton(app.GridLayout, 'push');
            app.ResetUIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUIButtonPushed, true);
            app.ResetUIButton.Text = 'Reset UI';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_CoRSEER_large.png');
            app.dotvarepsilonmathrmmmmathrmyr1Label = uilabel(app.GridLayout);
            app.dotvarepsilonmathrmmmmathrmyr1Label.Interpreter = 'latex';
            app.dotvarepsilonmathrmmmmathrmyr1Label.Text = '$\dot{\varepsilon}\;(\mathrm{mm.}\,\mathrm{yr}^{-1})$';
            app.dotvarepsilonmathrmmmmathrmyr1EditField = uieditfield(app.GridLayout, 'numeric');
            app.ModuleICalibrationLabel = uilabel(app.GridLayout);
            app.ModuleICalibrationLabel.Text = 'Module I -  Calibration';
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Line Object02.png');
            app.Image4 = uiimage(app.GridLayout);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Logo_IIT_Kanpur.png');
            app.InputParametersLabel = uilabel(app.GridLayout);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.OutputParametersLabel = uilabel(app.GridLayout);
            app.OutputParametersLabel.Text = 'Output Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoRSEER_I

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end