classdef CoRSEER_II < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        OutputParametersLabel       matlab.ui.control.Label
        InputParametersLabel        matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        ModuleIIAgeEstimationLabel  matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        FileSelectButton            matlab.ui.control.Button
        FileSelectButton_2          matlab.ui.control.Button
        ChooseFolderEditField       matlab.ui.control.EditField
        ChooseFolderEditFieldLabel  matlab.ui.control.Label
        log_10t_mathrmexposuremathrmyrSlider  matlab.ui.control.RangeSlider
        log_10t_mathrmexposuremathrmyrLabel  matlab.ui.control.Label
        LoadCalibrationDatasetEditField  matlab.ui.control.EditField
        mLabel                      matlab.ui.control.Label
        timestampdtaLabel           matlab.ui.control.Label
        LoadCalibrationDatasetEditFieldLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        SampleNameEditFieldLabel    matlab.ui.control.Label
        CodeProgressGauge           matlab.ui.control.Gauge
        Image                       matlab.ui.control.Image
        CodeProgressGaugeLabel      matlab.ui.control.Label
        ResetUIButton               matlab.ui.control.Button
        EstimateAgeButton           matlab.ui.control.Button
        mathrmBestFitmumathrmmm1EditField  matlab.ui.control.NumericEditField
        TimestampdtyrEditField      matlab.ui.control.NumericEditField
        dotvarepsilonmathrmmmmathrmyr1EditField  matlab.ui.control.NumericEditField
        dotvarepsilonmathrmmmmathrmyr1EditFieldLabel  matlab.ui.control.Label
        SampleNameEditField         matlab.ui.control.EditField
        mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField  matlab.ui.control.NumericEditField
        mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CalibExcelPath % Description
        CalibrationData % Description
        FolderPath
        BreakExecution % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            close all;
            clc;
        end

        % Button pushed function: ResetUIButton
        function ResetUIButtonPushed(app, event)
            app.BreakExecution = true;
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            % Clear any gauge or status fields if applicable
            app.CodeProgressGauge.Value = 0;
            app.UITable.Data = table();  % clear result table
            clear all;
            close all;
            clc;
        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx', 'Select Calibration Excel File');
            if isequal(file, 0)
                return;  % user cancelled
            end

            % Store file path
            app.CalibExcelPath = fullfile(path, file);
            app.LoadCalibrationDatasetEditField.Value = app.CalibExcelPath;
            % Load the data (reads only first sheet, numeric data only)
            C = readcell(app.CalibExcelPath);
            % Extract SP_BF and mu_BF
            try
                col_SP_BF = strcmp(C(1,:), 'SP_BF');
                col_mu_BF = strcmp(C(1,:), 'mu_BF');
                SP_BF_val = C{2, col_SP_BF};
                mu_BF_val = C{2, col_mu_BF};

            catch
                uialert(app.UIFigure, 'SP_BF or mu_BF not found in Excel.', 'Data Error');
                return;
            end

            % Populate the values in app fields
            app.mathrmBestFitmumathrmmm1EditField.Value = mu_BF_val;
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField.Value = SP_BF_val;
        end

        % Button pushed function: FileSelectButton_2
        function FileSelectButton_2Pushed(app, event)
            folderPath = uigetdir;  % Opens a folder selection dialog
            figure(app.UIFigure);   % Brings the App window to front (optional)
            if folderPath ~= 0
                app.FolderPath = folderPath;  % Store folder path
                cd(folderPath);               % Change MATLAB current directory
                app.ChooseFolderEditField.Value = folderPath;  % If you're using an edit field
            end
        end

        % Button pushed function: EstimateAgeButton
        function EstimateAgeButtonPushed(app, event)

            sheetName = strtrim(app.SampleNameEditField.Value);
            if isempty(sheetName)
                uialert(app.UIFigure, 'Please enter a valid sample name.', 'Missing Input');
                return;
            end
            excelFile = 'OSL_Unknown.xlsx';
            try
                % Check if sheet exists
                [~, sheets] = xlsfinfo(excelFile);
                if ~ismember(sheetName, sheets)
                    uialert(app.UIFigure, sprintf('Sheet "%s" not found in OSL_Unknown.xlsx', sheetName), 'Invalid Sheet');
                    return;
                end

                % Run your main calibration function
                runCoRSEERAge(app, sheetName);

                % Optional success message (non-intrusive)
                disp(['✅ Sheet Loaded Successfully: ' sheetName]);
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end
            function runCoRSEERAge(app, ~)
                % Load and extract experimental data
                sheetName = strtrim(app.SampleNameEditField.Value);
                if isempty(sheetName)
                    uialert(app.UIFigure, 'Please enter a valid sample name.', 'Missing Input');
                    return;
                end
                excelFile = 'OSL_Unknown.xlsx';
                [~, sheets] = xlsfinfo(excelFile);
                if ~ismember(sheetName, sheets)
                    uialert(app.UIFigure, sprintf('Sheet "%s" not found in OSL_Unknown.xlsx', sheetName), 'Invalid Sheet');
                    return;
                end
                %% 1 Loading Input Dataset
                data = readmatrix(excelFile, 'Sheet', sheetName); % input excel sheet
                depth = data(:, 1); % depth of experimental derived LDP in mm
                dmax = ceil(max(depth)/4)*4; % depth values which will be used for plotting best fitting sigmoidal fit
                LxTx = data(:, 2); % Unnormalised Lx/Tx values from Risoe analyst (unitless)
                LxTxErr = data(:, 3); % Unnormalised Lx/Tx error values from Riose analyst (unitless)
                mu_BF = app.mathrmBestFitmumathrmmm1EditField.Value; % Best fitting mu Value
                SP_BF = 10.^(app.mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField.Value); % Best fitting Sigmaphi Value
                dt = app.TimestampdtyrEditField.Value; % timestamp
                t_range = 10.^(app.log_10t_mathrmexposuremathrmyrSlider.Value); % Exposure time range on log-scale
                e_0 = app.dotvarepsilonmathrmmmmathrmyr1EditField.Value; % input erosion if any

                %% 2a Sigmoidal Curve Fitting (SCF) : Model, Input and Output
                ft = fittype('a / (1 + exp(-b*(x - c)))', 'independent', 'x', 'coefficients', {'a', 'b', 'c'});
                opts = fitoptions(ft);
                opts.StartPoint = [max(LxTx), 1, median(depth)];
                opts.Lower = [0, 0, 0];
                opts.Upper = [Inf, Inf, max(depth)];
                opts.Weights = abs(LxTx ./ LxTxErr);
                [fitresult, gof] = fit(depth, LxTx, ft, opts);
                ci = confint(fitresult, 0.95);
                a = fitresult.a; b = fitresult.b; c = fitresult.c;
                x_dense = linspace(0, ceil(max(depth)/4)*4, 2000);
                f_dense = a ./ (1 + exp(-b * (x_dense - c)));   

                %% 2b SCF: Plot_4
                cla(app.UIAxes); hold(app.UIAxes, 'on');
                errorbar(app.UIAxes, depth, LxTx, LxTxErr, 'ko');
                plot(app.UIAxes, x_dense, f_dense, '-', 'LineWidth', 2, 'Color', '#008080');
                xlabel(app.UIAxes, 'Depth (mm)');
                ylabel(app.UIAxes, 'Lx/Tx');
                title(app.UIAxes, ['Sigmoidal Fitting_', sheetName], 'Interpreter', 'none');
                legend(app.UIAxes,'Lx/TxExp','Sigmoidal fit','Location', 'southeast');
                abctxt = sprintf('a = %.4g\nb = %.4g\nc = %.4g', a, b, c);
                text(app.UIAxes, 0.90, 0.3, abctxt, 'Units','normalized','HorizontalAlignment','right', 'VerticalAlignment','bottom','FontSize',15);
                box(app.UIAxes, 'on');
                axis(app.UIAxes, 'square');
                ylim(app.UIAxes, [0, 1.25 * max(LxTx)]);
                xlim(app.UIAxes, [0, dmax]);
                f1 = figure('Visible','on');
                ax1 = copyobj(app.UIAxes, f1);
                set(f1, 'Position', get(app.UIAxes, 'Position'));    % match size (optional)
                savefig(f1, sprintf('Plot4_%s.fig', sheetName));
                close(f1);

                %% 2c Estimating Standard Deviation and Normalising Lx/Tx
                std_1 = std(abs(LxTx - feval(fitresult, depth))/a); % estimating residual values between best fitting sigmoidal curve and experimental LDP
                NLS_exp = LxTx / a; % Normalising Lx/Tx with plateau value
                NLS_err = LxTxErr / a; % Normalising Lx/Tx Error with plateau value
                %%% Completion of Normalisation Process %%%

                %% 3a Forward Modelling - Initial Declaration Variables
                r = data(1,4); % Order of Kinetics
                D_r = data(1,5); % Dose Rates in Gy/yr
                Do  = data(1,6); % Characteristic Dose in Gy
                Df  = D_r/Do; % Dose Factor
                nd  = 100; % depth interval taken for advection term simulation
                dx = dmax / (nd-1); % depth interval taken for advection term simulation
                d_syn = linspace(0, dmax, nd)'; % Synthetic LDP depth matrix
                n = ceil((t_range(2) - t_range(1)) / dt); % Estimating the number of divisions based on the provided time range
                t_mat = linspace(t_range(1), t_range(2), n); % Time matrix on log scale which is to be solved
                NLS_num = zeros(nd, n); % predefining the full NLS matrix which will be populated later
                i_mat = zeros(n,1); % recorder of the x0.5 index location
                erosion = [0.0001, 0.001, 0.01, 0.1, 1, e_0]; % Simulated erosion histories in mm/yr
                i_mat_all = zeros(n, numel(erosion)); % Recorder of the x0.5 for all erosion histories.
                j_0 = 0; % tracking overall code progress which is initially zero
                %% 3b Log-time sampling setup ---
                m_log = n;                          % number of log bins
                t1 = t_range(1);
                t2 = t_range(2);

                % guard for log10: ensure lower bound > 0
                if t1 <= 0
                    % use first positive time in your linear grid
                    t1_eff = min(t_mat(t_mat > 0));
                else
                    t1_eff = t1;
                end

                % targets in YEARS on a log scale
                t_targets = logspace(log10(t1_eff), log10(t2), m_log);

                % nearest indices on linear dt grid
                idx_log = round(interp1(t_mat, 1:n, t_targets, 'nearest', 'extrap'));

                % optional: enforce unique time steps to avoid duplicates
                [idx_log_unique, ia] = unique(idx_log, 'stable');
                
                %% 3c Forward Modelling - Estimation of Misfit
                for j_1 = 1:numel(erosion)
                    e_r = erosion(j_1); 
                    NLS_syn  = ones(nd,1);
                    for j = 1:n
                        p = [NLS_syn; 1];
                        dNLS = ((1 - NLS_syn) * Df - (NLS_syn.^r) .* SP_BF .* exp(-mu_BF * d_syn) * 365*24*3600 + e_r * diff(p) / dx) * dt;
                        NLS_syn = NLS_syn + dNLS;
                        NLS_syn = real(NLS_syn);
                        NLS_syn(NLS_syn > 1 | NLS_syn < 0 | isnan(NLS_syn) | isinf(NLS_syn)) = 0; %eliminating any negative, infinite and >1 value
                        [~, idx] = min(abs(NLS_syn - 0.5));
                        i_mat(j) = idx;
                        NLS_num(:, j) = NLS_syn;
                        app.CodeProgressGauge.Value = round(100 * (j_0 + j)/ (numel(erosion)*n));
                        drawnow limitrate;
                    end
                    j_0 = j_0 + j;
                    i_mat_all (:, j_1) = i_mat;
                end
                NLS_log      = NLS_num(:, idx_log_unique);   % profiles at nearest dt-steps
                t_log_matched = t_mat(idx_log_unique);       % actual times used
                mL = numel(idx_log_unique);                        % #log-sampled times

                %% 4a Inverse Modelling: Establishing the normalised likelihood matrix,Best fitting pairs of exposure time and Monte-Carlo filtered values
                E = interp1(d_syn, NLS_log, depth) - NLS_exp;     
                MF_vec = sum(E.^2, 1) / (std_1.^2);               
                likelihood = exp(-0.5 * MF_vec); % likelihood value estimated using Chi-square misfit function
                norm_likelihood = likelihood / max(likelihood); % normalised likelihood 0 to 1
                ind_MC_filt   = norm_likelihood > rand(1, mL);  % Index of pairs selected by monte carlo simulation
                MC_time     = t_log_matched(ind_MC_filt);      % Monte-Carlo filtered exposure age values on log-scale
                time_BF  = t_log_matched(norm_likelihood == 1);% Best fitting Value of exposure time values on log-scale

                %% 4b Inverse Modelling: Creating Confidence intervals from Monte-Carlo filtered Values
                conf_prct = [2.5, 17.5, 50, 82.5, 97.5];
                time_quant = prctile(MC_time, conf_prct);
                time_2sd = time_quant(1); % -2 sigma value of Exposure time
                time_1sd = time_quant(2); % -1 sigma value of Exposure time
                time_M   = time_quant(3); % Median Value of Exposure time
                time_1su = time_quant(4); % +1 sigma value of Exposure time
                time_2su = time_quant(5); % +2 sigma value of Exposure time

                %% 5a Final Results: Plot_5 of ±σ Age Fitting plot
                cla(app.UIAxes3); hold(app.UIAxes3, 'on');
                BF_idx = find(ind_MC_filt);
                z = numel(BF_idx);
                if z == 0
                    uialert(app.UIFigure, 'No profiles accepted in Monte Carlo sampling.', 'No Data');
                    return;
                end
                [~, sorted_idx] = sort(norm_likelihood(BF_idx), 'ascend');
                sorted_BF_idx = BF_idx(sorted_idx);
                NLS_sorted = NLS_log(:, sorted_BF_idx);
                numLinesToPlot = min(100, z);
                rand_idx = randperm(z, numLinesToPlot);
                cc = parula(z);
                for i = 1:numLinesToPlot
                    plot(app.UIAxes3, d_syn, NLS_sorted(:, rand_idx(i)), 'Color', cc(rand_idx(i),:));
                end
                errorbar(app.UIAxes3, depth, NLS_exp, NLS_err, 'ko');
                xlabel(app.UIAxes3, 'Depth (mm)'); ylabel(app.UIAxes3, 'NLS');
                title(app.UIAxes3, ['±σ Age Fitting', sheetName], 'Interpreter', 'none');
                ylim(app.UIAxes3, [0, 1.25 * max(NLS_exp)]); xlim(app.UIAxes3, [0, dmax]);
                axis(app.UIAxes3, 'square'); box(app.UIAxes3, 'on');
                cb = colorbar(app.UIAxes3, 'Ticks', [0 1], 'TickLabels', {'Low','High'});
                cb.Label.String = 'Probability';   % <-- correct way
                f2 = figure('Visible','on');
                ax2 = copyobj(app.UIAxes3, f2);
                set(f2, 'Position', get(app.UIAxes3, 'Position'));
                savefig(f2, sprintf('Plot5_%s.fig', sheetName));
                close(f2);

                %% 5b Final Results: Plot_6 of xo.5 vs time for different erosion scenarios
                cla(app.UIAxes4); hold(app.UIAxes4, 'on');
                d_mat = reshape(d_syn(i_mat_all(:)), size(i_mat_all));
                C = rand(6,3);
                for j_2 = 1:numel(erosion)
                    plot(app.UIAxes4, t_mat, d_mat(:,j_2), 'LineWidth', 2,'Color', C(j_2,:));
                end
                c_d = (max(d_mat(:,6))-1/mu_BF); 
                id = find(d_mat(:,6) > c_d, 1);
                id_1 = find(d_mat(:,6)> c,1);
                plot(app.UIAxes4, t_mat(id), d_mat(id,6), 'o', 'MarkerSize', 10, 'MarkerFaceColor', 'r','MarkerEdgeColor','r');
                plot(app.UIAxes4, t_mat(id_1), d_mat(id_1,6), 'o', 'MarkerSize', 10, 'MarkerFaceColor', 'b','MarkerEdgeColor','b');
                set(app.UIAxes4, 'XScale', 'log'); xlabel(app.UIAxes4, 't_{exposure} (a)'); ylabel(app.UIAxes4, 'x_{0.5} (mm)');
                xlim(app.UIAxes4, [min(t_mat), max(t_mat)]); ylim(app.UIAxes4, [0, 1.25 * max(d_mat(:,numel(erosion)))]);
                title(app.UIAxes4, ['x0.5 Propagation ', sheetName], 'Interpreter', 'none');
                axis(app.UIAxes4, 'square'); box(app.UIAxes4, 'on');
                lg = {'10^{-4} mm/a','10^{-3} mm/a','10^{-2} mm/a','10^{-1} mm/a','10^{0} mm/a',sprintf('%.3g mm/a', e_0)};
                legend(app.UIAxes4, lg{:}, 'Location','northwest');
                f3 = figure('Visible','on');
                ax3 = copyobj(app.UIAxes4, f3);
                set(f3, 'Position', get(app.UIAxes4, 'Position'));
                savefig(f3, sprintf('Plot6_%s.fig', sheetName));
                close(f3);
                %% 5c Final Results: Displaying Result Table and saving the variables
                resultsTable = table;
                resultsTable.time_BF = time_BF;
                resultsTable.time_M = time_M;
                resultsTable.time_1sd = time_1sd;
                resultsTable.time_1su = time_1su;
                resultsTable.time_2sd = time_2sd;
                resultsTable.time_2su = time_2su;
                resultsTable.OrderofKinetics = r;
                resultsTable.td = t_mat(id);
                resultsTable.xd = d_mat(id);
                resultsTable.a = a;
                resultsTable.a_1sd = ci(1,1); % -1 sigma value a
                resultsTable.a_1su = ci(2,1); % +1 sigma value a
                resultsTable.b = b;
                resultsTable.b_1sd = ci(1,2); % -1 sigma value b
                resultsTable.b_1su = ci(2,2); % +1 sigma value b
                resultsTable.c = c;
                resultsTable.c_1sd = ci(1,3); % -1 sigma value b
                resultsTable.c_1su = ci(2,3); % +1 sigma value b
                resultsTable.R_square = gof.rsquare;
                writetable(resultsTable, ['FitParAge_' sheetName, '.xlsx']);
                varNames = resultsTable.Properties.VariableNames';
                varValues = table2cell(resultsTable)';
                summaryCell = [varNames, varValues];
                app.UITable.Data = summaryCell;
                app.UITable.ColumnName = {'Parameter', 'Value'};
                save(sprintf('VariableAge_%s.mat', sheetName));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label = uilabel(app.GridLayout);
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label.Interpreter = 'latex';
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label.Text = '$\mathrm{Best\ Fit}\log_{10}(\overline{\sigma\varphi}_{\mathrm{o}})\(';
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleNameEditField = uieditfield(app.GridLayout, 'text');
            app.dotvarepsilonmathrmmmmathrmyr1EditFieldLabel = uilabel(app.GridLayout);
            app.dotvarepsilonmathrmmmmathrmyr1EditFieldLabel.Interpreter = 'latex';
            app.dotvarepsilonmathrmmmmathrmyr1EditFieldLabel.Text = '$\dot{\varepsilon}\;(\mathrm{mm.}\,\mathrm{yr}^{-1})$';
            app.dotvarepsilonmathrmmmmathrmyr1EditField = uieditfield(app.GridLayout, 'numeric');
            app.TimestampdtyrEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimestampdtyrEditField.Value = 0.1;
            app.mathrmBestFitmumathrmmm1EditField = uieditfield(app.GridLayout, 'numeric');
            app.EstimateAgeButton = uibutton(app.GridLayout, 'push');
            app.EstimateAgeButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateAgeButtonPushed, true);
            app.EstimateAgeButton.Text = 'Estimate Age';
            app.ResetUIButton = uibutton(app.GridLayout, 'push');
            app.ResetUIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUIButtonPushed, true);
            app.ResetUIButton.Text = 'Reset UI';
            app.CodeProgressGaugeLabel = uilabel(app.GridLayout);
            app.CodeProgressGaugeLabel.Text = 'Code Progress (%)';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_CoRSEER_large.png');
            app.CodeProgressGauge = uigauge(app.GridLayout, 'circular');
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout);
            app.SampleNameEditFieldLabel.Interpreter = 'latex';
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.LoadCalibrationDatasetEditFieldLabel = uilabel(app.GridLayout);
            app.LoadCalibrationDatasetEditFieldLabel.Text = 'Load Calibration Dataset';
            app.timestampdtaLabel = uilabel(app.GridLayout);
            app.timestampdtaLabel.Interpreter = 'latex';
            app.timestampdtaLabel.Text = 'Timestamp (dt) (yr)';
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Interpreter = 'latex';
            app.mLabel.Text = '$\mathrm{Best\ Fit}\ \mu\,(\mathrm{mm}^{-1})$';
            app.LoadCalibrationDatasetEditField = uieditfield(app.GridLayout, 'text');
            app.log_10t_mathrmexposuremathrmyrLabel = uilabel(app.GridLayout);
            app.log_10t_mathrmexposuremathrmyrLabel.Interpreter = 'latex';
            app.log_10t_mathrmexposuremathrmyrLabel.Text = '$\log_{10}(t\_\mathrm{exposure})\,(\mathrm{yr})$';
            app.log_10t_mathrmexposuremathrmyrSlider = uislider(app.GridLayout, 'range');
            app.log_10t_mathrmexposuremathrmyrSlider.Limits = [-1 6];
            app.log_10t_mathrmexposuremathrmyrSlider.MajorTicks = [-1 0 1 2 3 4 5 6];
            app.log_10t_mathrmexposuremathrmyrSlider.MajorTickLabels = {'-1', '0', '1', '2', '3', '4', '5', '6'};
            app.log_10t_mathrmexposuremathrmyrSlider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6];
            app.log_10t_mathrmexposuremathrmyrSlider.Step = 0.007;
            app.log_10t_mathrmexposuremathrmyrSlider.Value = [-1 6];
            app.ChooseFolderEditFieldLabel = uilabel(app.GridLayout);
            app.ChooseFolderEditFieldLabel.Text = 'Choose Folder';
            app.ChooseFolderEditField = uieditfield(app.GridLayout, 'text');
            app.FileSelectButton_2 = uibutton(app.GridLayout, 'push');
            app.FileSelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButton_2Pushed, true);
            app.FileSelectButton_2.Icon = fullfile(pathToMLAPP, 'Icon_folder.png');
            app.FileSelectButton_2.Text = '';
            app.FileSelectButton = uibutton(app.GridLayout, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Icon = fullfile(pathToMLAPP, 'Icon_MS_Excel.png');
            app.FileSelectButton.Text = '';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Logo_IIT_Kanpur.png');
            app.ModuleIIAgeEstimationLabel = uilabel(app.GridLayout);
            app.ModuleIIAgeEstimationLabel.Text = 'Module II - Age Estimation';
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Line Object02.png');
            app.InputParametersLabel = uilabel(app.GridLayout);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.OutputParametersLabel = uilabel(app.GridLayout);
            app.OutputParametersLabel.Text = 'Output Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoRSEER_II

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end