classdef CoRSEER_III < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        FileSelectButton               matlab.ui.control.Button
        OutputParametersLabel          matlab.ui.control.Label
        InputParametersLabel           matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        ModuleIIIErosionEstimationLabel  matlab.ui.control.Label
        dotvarepsilonmathrmmmmathrmyr1Slider  matlab.ui.control.RangeSlider
        log10ErosionmmaRangeLabel      matlab.ui.control.Label
        MaximumPossibleAgeyrEditFieldLabel  matlab.ui.control.Label
        MaximumPossibleAgeyrEditField  matlab.ui.control.NumericEditField
        FileSelectButton_2             matlab.ui.control.Button
        ChooseFolderEditField          matlab.ui.control.EditField
        ChooseFolderEditFieldLabel     matlab.ui.control.Label
        LoadCalibrationDatasetEditField  matlab.ui.control.EditField
        mLabel                         matlab.ui.control.Label
        timestampdtaLabel              matlab.ui.control.Label
        LoadCalibrationDatasetEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        SampleNameEditFieldLabel       matlab.ui.control.Label
        CodeProgressGauge              matlab.ui.control.Gauge
        Image                          matlab.ui.control.Image
        CodeProgressGaugeLabel         matlab.ui.control.Label
        ResetUIButton                  matlab.ui.control.Button
        EstimateErosionHistoryButton   matlab.ui.control.Button
        mathrmBestFitmumathrmmm1EditField  matlab.ui.control.NumericEditField
        ResolutionEditField            matlab.ui.control.NumericEditField
        SampleNameEditField            matlab.ui.control.EditField
        mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField  matlab.ui.control.NumericEditField
        mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CalibExcelPath % Description
        CalibrationData % Description
        FolderPath
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            close all;
            clc;
        end

        % Button pushed function: ResetUIButton
        function ResetUIButtonPushed(app, event)

            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes2, 'reset');

            % Clear any gauge or status fields if applicable
            app.CodeProgressGauge.Value = 0;
            app.UITable.Data = table();  % clear result table

            clear all;
            close all;
            clc;
        end

        % Button pushed function: FileSelectButton
        function FileSelectButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx', 'Select Calibration Excel File');
            if isequal(file, 0)
                return;  % user cancelled
            end

            % Store file path
            app.CalibExcelPath = fullfile(path, file);

            app.LoadCalibrationDatasetEditField.Value = app.CalibExcelPath;
            % Load the data (reads only first sheet, numeric data only)
            C = readcell(app.CalibExcelPath);

            % Extract SP_BF and mu_BF
            try
                col_SP_BF = strcmp(C(1,:), 'SP_BF');
                col_mu_BF = strcmp(C(1,:), 'mu_BF');
                SP_BF_val = C{2, col_SP_BF};
                mu_BF_val = C{2, col_mu_BF};

            catch
                uialert(app.UIFigure, 'SP_BF or mu_BF not found in Excel.', 'Data Error');
                return;
            end

            % Populate the values in app fields
            app.mathrmBestFitmumathrmmm1EditField.Value = mu_BF_val;
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField.Value = SP_BF_val;
        end

        % Button pushed function: FileSelectButton_2
        function FileSelectButton_2Pushed(app, event)
            folderPath = uigetdir;  % Opens a folder selection dialog
            figure(app.UIFigure);   % Brings the App window to front (optional)
            if folderPath ~= 0
                app.FolderPath = folderPath;  % Store folder path
                cd(folderPath);               % Change MATLAB current directory
                app.ChooseFolderEditField.Value = folderPath;  % If you're using an edit field
            end
        end

        % Button pushed function: EstimateErosionHistoryButton
        function EstimateErosionHistoryButtonPushed(app, event)

            sheetName = strtrim(app.SampleNameEditField.Value);
            if isempty(sheetName)
                uialert(app.UIFigure, 'Please enter a valid sample name.', 'Missing Input');
                return;
            end
            excelFile = 'OSL_Unknown.xlsx';
            try
                % Check if sheet exists
                [~, sheets] = xlsfinfo(excelFile);
                if ~ismember(sheetName, sheets)
                    uialert(app.UIFigure, sprintf('Sheet "%s" not found in OSL_Unknown.xlsx', sheetName), 'Invalid Sheet');
                    return;
                end

                % Run your main calibration function
                runCoRSEERAge(app, sheetName);

                % Optional success message (non-intrusive)
                disp(['✅ Sheet Loaded Successfully: ' sheetName]);
            catch ME
                uialert(app.UIFigure, sprintf('Error: %s', ME.message), 'Execution Failed');
            end

            function runCoRSEERAge(app, ~)
                tic
                % Load and extract experimental data
                sheetName = strtrim(app.SampleNameEditField.Value);
                if isempty(sheetName)
                    uialert(app.UIFigure, 'Please enter a valid sample name.', 'Missing Input');
                    return;
                end
                excelFile = 'OSL_Unknown.xlsx';
                [~, sheets] = xlsfinfo(excelFile);
                if ~ismember(sheetName, sheets)
                    uialert(app.UIFigure, sprintf('Sheet "%s" not found in OSL_Unknown.xlsx', sheetName), 'Invalid Sheet');
                    return;
                end
                %% 1 Loading Input Dataset
                data = readmatrix(excelFile, 'Sheet', sheetName);
                depth = data(:, 1); % depth of experimental derived LDP in mm
                dmax = ceil(max(depth)/4)*4; 
                LxTx = data(:, 2);  % Unnormalised Lx/Tx values from Risoe analyst (unitless)
                LxTxErr = data(:, 3); % Unnormalised Lx/Tx error values from Riose analyst (unitless)
                mu_BF = app.mathrmBestFitmumathrmmm1EditField.Value; % Best-fit Mu values
                SP_BF = 10.^(app.mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField.Value); %Best-Fit sigma-phi values
                m = app.ResolutionEditField.Value; %number of iterations.
                e_lim = (app.dotvarepsilonmathrmmmmathrmyr1Slider.Value); % range of erosion rates in mm/a
                e_range = 10.^linspace(e_lim(1), e_lim(2), m);  %
                t_range = 10.^linspace(-1, log10(app.MaximumPossibleAgeyrEditField.Value), m);

                %% 2a Sigmoidal Curve Fitting (SCF) : Model, Input and Output
                ft = fittype('a / (1 + exp(-b*(x - c)))', 'independent', 'x', 'coefficients', {'a', 'b', 'c'});
                opts = fitoptions(ft);
                opts.StartPoint = [max(LxTx), 1, median(depth)];
                opts.Lower = [0, 0, 0];
                opts.Upper = [Inf, Inf, max(depth)];
                opts.Weights = (LxTx ./ LxTxErr);
                [fitresult, gof] = fit(depth, LxTx, ft, opts);
                ci = confint(fitresult, 0.95);
                a = fitresult.a; b = fitresult.b; c = fitresult.c;
                x_dense = linspace(0, ceil(max(depth)/4)*4, 2000);
                f_dense = a ./ (1 + exp(-b * (x_dense - c)));

                %% 2b SCF: Plot_7
                cla(app.UIAxes); hold(app.UIAxes, 'on');
                errorbar(app.UIAxes, depth, LxTx, LxTxErr, 'ko');
                plot(app.UIAxes, x_dense, f_dense, '-', 'LineWidth', 2, 'Color', '#008080');
                xlabel(app.UIAxes, 'Depth (mm)');
                ylabel(app.UIAxes, 'Lx/Tx');
                title(app.UIAxes, ['Sigmoidal Fitting_', sheetName], 'Interpreter', 'none');
                legend(app.UIAxes,'Lx/TxExp','Sigmoidal fit','Location', 'southeast');
                abctxt = sprintf('a = %.4g\nb = %.4g\nc = %.4g', a, b, c);
                text(app.UIAxes, 0.90, 0.3, abctxt, 'Units','normalized','HorizontalAlignment','right', 'VerticalAlignment','bottom','FontSize',15);
                box(app.UIAxes, 'on');
                axis(app.UIAxes, 'square');
                ylim(app.UIAxes, [0, 1.25 * max(LxTx)]);
                xlim(app.UIAxes, [0, dmax]);
                f1 = figure('Visible','on');
                ax1 = copyobj(app.UIAxes, f1);
                set(f1, 'Position', get(app.UIAxes, 'Position'));    % match size (optional)
                savefig(f1, sprintf('Plot_7_%s.fig', sheetName));
                close(f1);

                %% 2c Estimating Standard Deviation and Normalising Lx/Tx
                std_1 = std(abs(LxTx - feval(fitresult, depth))/a); % estimating residual values between best fitting sigmoidal curve and experimental LDP
                NLS_exp = LxTx / a; % Normalising Lx/Tx with plateau value
                NLS_err = LxTxErr / a; % Normalising Lx/Tx Error with plateau value
                %%% Completion of Normalisation Process %%%

                %% 3a Forward Modelling - Initial Declaration Variables
                [log_e_grid, log_t_grid] = meshgrid(e_range, t_range);
                e_vec  = log_e_grid(1, :);  % erosion search range matrix
                ts_vec = log_t_grid(:, 1);  % onset time of erosion matrix    
                r = data(1,4); % Order of kinetics
                D_r = data(1,5);% Dose rate in Gy/yr
                Do  = data(1,6); % Characteristic Dose in Gray
                Df  = D_r/Do; % Dose Factor
                dt = 0.01; % timestamp in years
                nd = 50; % depth division
                dx = dmax / (nd-1); %depth interval taken for advection term simulation
                d_syn = linspace(0, dmax, nd)'; % Synthetic LDP depth matrix
                sec_per_year = 365 * 24 * 3600; % transforming into seconds to year
                MF_mat = zeros(m, m); % initial declaration of misfit matrix
                NLS_syn_num = zeros(nd, m, m); % initial declaration of synthetic NLS matrix

                %% 3b Forward Modelling - Estimation of Misfit
                for i = 1:m
                    e_r = e_vec(i);
                    i
                    parfor j = 1:m
                        t_2 = ts_vec(j);                % total exposure
                        t_1 = max(ts_vec)-t_2;          % erosion onset

                        n_1 = round(t_1 / dt);
                        n_2 = round(t_2 / dt);

                        NLS_syn = ones(nd,1);

                        % -------- Phase 1: No erosion --------
                        for t = 1:n_1
                            dNLS = ((1 - NLS_syn) * Df ...
                                - (real(NLS_syn.^r)) .* SP_BF .* exp(-mu_BF * d_syn) * sec_per_year) * dt;

                            NLS_syn = NLS_syn + dNLS;
                            NLS_syn(NLS_syn > 1 | NLS_syn < 0 | isnan(NLS_syn) | isinf(NLS_syn)) = 0;
                        end

                        % -------- Phase 2: With erosion --------
                        for t = 1:n_2
                            p = [NLS_syn; 1];
                            dNLS = ((1 - NLS_syn) * Df ...
                                - (real(NLS_syn.^r)) .* SP_BF .* exp(-mu_BF * d_syn) * sec_per_year ...
                                + e_r * diff(p) / dx) * dt;

                            NLS_syn = NLS_syn + dNLS;
                            NLS_syn(NLS_syn > 1 | NLS_syn < 0 | isnan(NLS_syn) | isinf(NLS_syn)) = 0;
                        end
                        NLS_syn_num(:, j, i) = NLS_syn;
                        NLS_iplt      = interp1(d_syn, NLS_syn, depth);
                        MF_mat(i,j)   = sum(abs(NLS_iplt - NLS_exp)) / std_1;
                    end
                    app.CodeProgressGauge.Value = round(100 * i / m);
                    drawnow limitrate;
                end


                %% 4a Inverse Modelling: Establishing the normalised likelihood matrix,Best fitting pairs of exposure time and erosion rates
                likelihood       = exp(-MF_mat / 2);             % Likelihood
                norm_likelihood  = likelihood / max(likelihood(:));            % Normalized likelihood
                % Compatibility aliases for output and post-processing.
                % norm_chi_et is always arranged as rows = erosion-rate, columns = erosion-onset time.
                chi = likelihood;
                norm_chi = norm_likelihood;
                if isequal(size(norm_chi), [numel(e_vec), numel(ts_vec)])
                    norm_chi_et = norm_chi;
                elseif isequal(size(norm_chi), [numel(ts_vec), numel(e_vec)])
                    norm_chi_et = norm_chi';
                else
                    error('CoRSEER:NormChiSizeMismatch', ...
                        'The normalized chi/likelihood matrix does not match e_vec and ts_vec dimensions.');
                end

                %% 4b Steady-state ridge detection and Gaussian erosion-rate estimate
                [best_chi_by_ts, best_e_idx_by_ts] = max(norm_chi_et, [], 1);
                best_e_by_ts = e_vec(best_e_idx_by_ts);
                log_best_e_by_ts = log10(best_e_by_ts(:));
                log_ts_for_ridge = log10(ts_vec(:));
                log_e_axis = log10(e_vec(:));
                eGridStepLog = median(abs(diff(log_e_axis)));

                % Smooth the ridge only for detecting the steady segment; the final
                % distribution is calculated from the original column, not from the smooth ridge.
                ridgeWin = max(3, 2 * floor(0.04 * m / 2) + 1);
                ridgeWin = min(ridgeWin, numel(log_best_e_by_ts));
                if mod(ridgeWin, 2) == 0
                    ridgeWin = ridgeWin - 1;
                end
                if ridgeWin >= 3
                    log_best_e_smooth = movmedian(log_best_e_by_ts, ridgeWin);
                else
                    log_best_e_smooth = log_best_e_by_ts;
                end

                steady_col = NaN;
                steadyIndexTolerance = 1;          % erosion-grid cells allowed in the steady tail
                steadySlopeTolerance = 0.0;       % |dlog10(E)/dlog10(TS)| threshold
                steadyMinCols = max(3, ceil(0.08 * m));

                % Primary criterion: earliest TS after which the remaining ridge stays
                % within approximately one erosion-grid cell. This avoids choosing the
                % initial high-erosion boundary plateau before the ridge starts tilting.
                for jj = 1:(m - steadyMinCols + 1)
                    ridgeHasMoved = (max(log_best_e_smooth(1:jj)) - min(log_best_e_smooth(1:jj))) >= 2 * eGridStepLog;
                    tailIdxRange = max(best_e_idx_by_ts(jj:end)) - min(best_e_idx_by_ts(jj:end));
                    if ridgeHasMoved && tailIdxRange <= steadyIndexTolerance
                        steady_col = jj+steadyMinCols/2;
                        break;
                    end
                end

                % Secondary criterion: if the ridge is not perfectly flat, accept the
                % earliest section where the local log-log slope is close to zero.
                if isnan(steady_col)
                    localSlope = abs(gradient(log_best_e_smooth, log_ts_for_ridge));
                    for jj = 1:(m - steadyMinCols + 1)
                        ridgeHasMoved = (max(log_best_e_smooth(1:jj)) - min(log_best_e_smooth(1:jj))) >= 2 * eGridStepLog;
                        testIdx = jj:min(m, jj + steadyMinCols - 1);
                        if ridgeHasMoved && all(localSlope(testIdx) <= steadySlopeTolerance)
                            steady_col = jj+steadyMinCols/2;
                            break;
                        end
                    end
                end

                % Fallback: if a steady ridge is not present within the supplied TS range,
                % use the global maximum and keep the index visible in the output table.
                if isnan(steady_col)
                    [~, globalBestIdx] = max(norm_chi_et(:));
                    [steady_row_idx, steady_col] = ind2sub(size(norm_chi_et), globalBestIdx);
                else
                    steady_row_idx = best_e_idx_by_ts(steady_col);
                end

                steady_ts = ts_vec(steady_col);
                steady_e_BF = e_vec(steady_row_idx);

                % Gaussian approximation of the selected vertical likelihood column.
                % Because erosion-rate is log-spaced, the Gaussian is estimated in
                % log10(erosion-rate) space and then reported back in mm/yr.
                p_col = norm_chi_et(:, steady_col);
                e_col = e_vec(:);
                validGaussianIdx = isfinite(p_col) & isfinite(e_col) & e_col > 0 & p_col > 0;
                if ~any(validGaussianIdx)
                    error('CoRSEER:GaussianColumnEmpty', ...
                        'The selected steady-state column contains no valid probability values.');
                end
                p_weight = p_col(validGaussianIdx);
                e_weight = e_col(validGaussianIdx);
                p_weight = p_weight ./ sum(p_weight);
                log_e_weight = log10(e_weight);
                e_mu_log = sum(p_weight .* log_e_weight);
                e_sigma_log = sqrt(sum(p_weight .* (log_e_weight - e_mu_log).^2));
                if ~isfinite(e_sigma_log) || e_sigma_log == 0
                    e_sigma_log = eGridStepLog;
                end
                e_mean_steady = 10.^e_mu_log;
                e_1sd_steady = 10.^(e_mu_log - e_sigma_log);
                e_1su_steady = 10.^(e_mu_log + e_sigma_log);

                % Gaussian curve for plotting only.
                log_e_fine = linspace(min(log_e_axis), max(log_e_axis), 400);
                gauss_pdf = exp(-0.5 .* ((log_e_fine - e_mu_log) ./ e_sigma_log).^2);
                gauss_pdf = gauss_pdf ./ max(gauss_pdf);
                e_fine = 10.^log_e_fine;

                % Grids saved for reference in the output MAT-file.
                % [E_grid, T_grid] = meshgrid(e_vec, ts_vec);   % rows = TS, columns = erosion-rate

                %% 5a Final Results: Plot_8- The surface plot of erosion time and erosion rates
                cla(app.UIAxes3);
                hold(app.UIAxes3, 'on');
                % Surface plot with log-log axes
                surf(app.UIAxes3, log_t_grid', log_e_grid', norm_chi_et, 'EdgeColor', 'none');
                set(app.UIAxes3, 'XScale', 'log', 'YScale', 'log');
                xlabel(app.UIAxes3, 'Erosion time (a)');
                ylabel(app.UIAxes3, 'Erosion Rate (mm/a)');
                zlabel(app.UIAxes3, 'Normalized Likelihood (\chi)');
                title(app.UIAxes3, ['Probability', sheetName], 'Interpreter', 'none');
                xlim(app.UIAxes3, [min(t_range), max(t_range)]);
                ylim(app.UIAxes3, [min(e_range), max(e_range)]);
                colorbar(app.UIAxes3);
                shading(app.UIAxes3, 'interp');
                view(app.UIAxes3, 2);           % Top-down view
                axis(app.UIAxes3, 'square');
                box(app.UIAxes3, 'on');
                plot3(app.UIAxes3, steady_ts, steady_e_BF, 1.05, 'ko', ...
                    'MarkerFaceColor', 'w', 'MarkerSize', 7, 'LineWidth', 1.2);
                f2 = figure('Visible','on');
                ax1 = copyobj(app.UIAxes3, f2);
                set(f2, 'Position', get(app.UIAxes3, 'Position'));
                savefig(f2, sprintf('Plot8_%s.fig', sheetName));
                close(f2);
                hold(app.UIAxes3, 'off');

                %% 5b Final Results: Plot_9 - Gaussian erosion-rate distribution at steady-state TS
                cla(app.UIAxes2);
                hold(app.UIAxes2, 'on');
                p_col_plot = p_col ./ max(p_col);
                hCol = semilogx(app.UIAxes2, e_col, p_col_plot, 'o', 'MarkerSize', 5, 'LineWidth', 1);
                hGauss = semilogx(app.UIAxes2, e_fine, gauss_pdf, '-', 'LineWidth', 2);
                set(app.UIAxes2, 'XScale', 'log');
                ylim(app.UIAxes2, [0, 1.25]);
                xlim(app.UIAxes2, [min(e_vec), max(e_vec)]);
                hMean = plot(app.UIAxes2, [e_mean_steady e_mean_steady], [0 1.25], 'k-', 'LineWidth', 1.5);
                h1SD = plot(app.UIAxes2, [e_1sd_steady e_1sd_steady], [0 1.25], 'k--', 'LineWidth', 1.2);
                h1SU = plot(app.UIAxes2, [e_1su_steady e_1su_steady], [0 1.25], 'k:', 'LineWidth', 1.2);
                xlabel(app.UIAxes2, 'Erosion Rate (mm/yr)');
                ylabel(app.UIAxes2, 'Normalized Probability');
                title(app.UIAxes2, ['Steady-state erosion distribution_', sheetName], 'Interpreter', 'none');
                legend(app.UIAxes2, [hGauss hMean h1SD h1SU], ...
                    {'Gaussian fit', 'Mean', '-1sigma', '+1sigma'}, 'Location', 'northwest');
                box(app.UIAxes2, 'on');
                axis(app.UIAxes2, 'square');
                f3 = figure('Visible','on');
                ax3 = copyobj(app.UIAxes2, f3);
                set(f3, 'Position', get(app.UIAxes2, 'Position'));
                savefig(f3, sprintf('Plot9_%s.fig', sheetName));
                close(f3);
                hold(app.UIAxes2, 'off');
                %% 5c Final Results: Displaying Result Table and saving the variables
                resultsTable = table;
                resultsTable.a = a;
                resultsTable.a_1sd = ci(1,1); % -1 sigma value a
                resultsTable.a_1su = ci(2,1); % +1 sigma value a
                resultsTable.b = b;
                resultsTable.b_1sd = ci(1,2); % -1 sigma value b
                resultsTable.b_1su = ci(2,2); % +1 sigma value b
                resultsTable.c = c;
                resultsTable.c_1sd = ci(1,3); % -1 sigma value b
                resultsTable.c_1su = ci(2,3); % +1 sigma value b
                resultsTable.R_square = gof.rsquare;
                resultsTable.ts_best_fit = steady_ts;
                resultsTable.e_1sd = e_1sd_steady;
                resultsTable.e_1su = e_1su_steady;
                resultsTable.e_M = e_mean_steady;
                writetable(resultsTable, ['FitErPar_' sheetName, '.xlsx']);
                % Convert table to cell array format
                varNames = resultsTable.Properties.VariableNames';
                varValues = table2cell(resultsTable)';
                % Combine as 2-column cell array
                summaryCell = [varNames, varValues];
                % % Set to UITable
                app.UITable.Data = summaryCell;
                app.UITable.ColumnName = {'Parameter', 'Value'};
                save(sprintf('Variable_ErCal_%s.mat', sheetName));
                beep on;
                toc
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label = uilabel(app.GridLayout);
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label.Interpreter = 'latex';
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmomathrms1Label.Text = '$\mathrm{Best\ Fit}\log_{10}(\overline{\sigma\varphi}_{\mathrm{o}})\(';
            app.mathrmBestFitlog_10overlinesigmavarphi_mathrmoEditField = uieditfield(app.GridLayout, 'numeric');
            app.SampleNameEditField = uieditfield(app.GridLayout, 'text');
            app.ResolutionEditField = uieditfield(app.GridLayout, 'numeric');
            app.ResolutionEditField.Value = 1000;
            app.mathrmBestFitmumathrmmm1EditField = uieditfield(app.GridLayout, 'numeric');
            app.EstimateErosionHistoryButton = uibutton(app.GridLayout, 'push');
            app.EstimateErosionHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateErosionHistoryButtonPushed, true);
            app.EstimateErosionHistoryButton.Text = 'Estimate Erosion History';
            app.ResetUIButton = uibutton(app.GridLayout, 'push');
            app.ResetUIButton.ButtonPushedFcn = createCallbackFcn(app, @ResetUIButtonPushed, true);
            app.ResetUIButton.Text = 'Reset UI';
            app.CodeProgressGaugeLabel = uilabel(app.GridLayout);
            app.CodeProgressGaugeLabel.Text = 'Code Progress (%)';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_CoRSEER_large.png');
            app.CodeProgressGauge = uigauge(app.GridLayout, 'circular');
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout);
            app.SampleNameEditFieldLabel.Interpreter = 'latex';
            app.SampleNameEditFieldLabel.Text = 'Sample Name';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.LoadCalibrationDatasetEditFieldLabel = uilabel(app.GridLayout);
            app.LoadCalibrationDatasetEditFieldLabel.Text = 'Load Calibration Dataset';
            app.timestampdtaLabel = uilabel(app.GridLayout);
            app.timestampdtaLabel.Interpreter = 'latex';
            app.timestampdtaLabel.Text = 'Resolution';
            app.mLabel = uilabel(app.GridLayout);
            app.mLabel.Interpreter = 'latex';
            app.mLabel.Text = '$\mathrm{Best\ Fit}\ \mu\,(\mathrm{mm}^{-1})$';
            app.LoadCalibrationDatasetEditField = uieditfield(app.GridLayout, 'text');
            app.ChooseFolderEditFieldLabel = uilabel(app.GridLayout);
            app.ChooseFolderEditFieldLabel.Text = 'Choose Folder';
            app.ChooseFolderEditField = uieditfield(app.GridLayout, 'text');
            app.FileSelectButton_2 = uibutton(app.GridLayout, 'push');
            app.FileSelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButton_2Pushed, true);
            app.FileSelectButton_2.Icon = fullfile(pathToMLAPP, 'Icon_folder.png');
            app.FileSelectButton_2.Text = '';
            app.MaximumPossibleAgeyrEditField = uieditfield(app.GridLayout, 'numeric');
            app.MaximumPossibleAgeyrEditField.Value = 1;
            app.MaximumPossibleAgeyrEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumPossibleAgeyrEditFieldLabel.Interpreter = 'latex';
            app.MaximumPossibleAgeyrEditFieldLabel.Text = 'Maximum Possible Age (yr)';
            app.log10ErosionmmaRangeLabel = uilabel(app.GridLayout);
            app.log10ErosionmmaRangeLabel.Interpreter = 'latex';
            app.log10ErosionmmaRangeLabel.Text = '$\dot{\varepsilon}\;(\mathrm{mm.}\,\mathrm{yr}^{-1})$';
            app.dotvarepsilonmathrmmmmathrmyr1Slider = uislider(app.GridLayout, 'range');
            app.dotvarepsilonmathrmmmmathrmyr1Slider.Limits = [-6 1];
            app.dotvarepsilonmathrmmmmathrmyr1Slider.MajorTicks = [-6 -5 -4 -3 -2 -1 0 1];
            app.dotvarepsilonmathrmmmmathrmyr1Slider.MajorTickLabels = {'-6', '-5', '-4', '-3', '-2', '-1', '0', '1'};
            app.dotvarepsilonmathrmmmmathrmyr1Slider.Value = [-6 1];
            app.ModuleIIIErosionEstimationLabel = uilabel(app.GridLayout);
            app.ModuleIIIErosionEstimationLabel.Text = 'Module III - Erosion Estimation';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Logo_IIT_Kanpur.png');
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Line Object02.png');
            app.InputParametersLabel = uilabel(app.GridLayout);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.OutputParametersLabel = uilabel(app.GridLayout);
            app.OutputParametersLabel.Text = 'Output Parameters';
            app.FileSelectButton = uibutton(app.GridLayout, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectButtonPushed, true);
            app.FileSelectButton.Icon = fullfile(pathToMLAPP, 'Icon_MS_Excel.png');
            app.FileSelectButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoRSEER_III

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end